classdef Scope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        CH1Label         matlab.ui.control.Label
        VLabel           matlab.ui.control.Label
        CH2Label         matlab.ui.control.Label
        VLabel_2         matlab.ui.control.Label
        TLabel           matlab.ui.control.Label
        Label            matlab.ui.control.Label
        TimeKnob_2Label  matlab.ui.control.Label
        TimeKnob         matlab.ui.control.DiscreteKnob
        CH1Label_2       matlab.ui.control.Label
        CH1Knob          matlab.ui.control.DiscreteKnob
        CH2Label_2       matlab.ui.control.Label
        CH2Knob          matlab.ui.control.DiscreteKnob
        CH1CheckBox      matlab.ui.control.CheckBox
        CH2CheckBox      matlab.ui.control.CheckBox
        CHD1CheckBox     matlab.ui.control.CheckBox
        CHD2CheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
         % Serial
         s = serial('COM1')
         baudrate = 115200;
         buffersize = 512;
         pi = 3.1415;
         % General parameters
         window = 512;
         fs = 2000;
         ts
         t
         p
         cha_ana
         cha_dig
         chb_ana
         chb_dig
         t1 = 0;
         t0 = 0;
         % Timer (for refresh plot)
         timer = timer;
         fps = 10;
         temp = 1;
    end
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s);
            disp(data)
        end
        
        function test(app)
            app.t1 = toc;
            %disp(app.t1 - app.t0);
            app.t0 = toc;
            addpoints(app.cha_ana, app.t(app.temp:((app.window/64)+app.temp)), 0.5*sin(2*app.pi*1000*app.t(app.temp:((app.window/64)+app.temp))));
            addpoints(app.chb_ana, app.t(app.temp:((app.window/64)+app.temp)), cos(2*app.pi*2000*app.t(app.temp:((app.window/64)+app.temp))));
            app.temp = app.temp + (app.window/64);
            %drawnow limitrate
        end
                
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = 320;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @readSerial;
        end
    
        function initTimer(app)
            % Timer settings
            app.timer.StartFcn = @(~,thisEvent)disp([thisEvent.Type ' executed ', datestr(thisEvent.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            %app.timer.TimerFcn = @(~,thisEvent)disp([thisEvent.Type ' executed ', datestr(thisEvent.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            app.timer.TimerFcn = @(~,~)app.test;
            app.timer.Period = 1/app.fps;
            app.timer.ExecutionMode = 'fixedRate';
        end
        
        function initAnimatedLines(app)
            app.cha_ana = animatedline(app.UIAxes, 'Color',[0.00,0.45,0.74]);
            app.chb_ana = animatedline(app.UIAxes, 'Color',[0.85,0.33,0.10]);
            app.cha_dig = animatedline(app.UIAxes, 'Color',[0.93,0.69,0.13]);
            app.chb_dig = animatedline(app.UIAxes, 'Color',[0.47,0.67,0.19]);
            app.cha_ana.MaximumNumPoints = app.window;
            app.chb_ana.MaximumNumPoints = app.window;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initSerial;
            app.initAnimatedLines;
            app.initTimer;
            
            % Sample time
            app.ts = 1/app.fs;
            app.t = linspace(0,0.05,app.window*10);
            app.cha_ana.MaximumNumPoints = app.window;
            app.chb_ana.MaximumNumPoints = app.window;

            start(app.timer);
            tic;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            stop(app.timer);
            delete(app)
        end

        % Value changed function: TimeKnob
        function TimeKnobValueChanged(app, event)
            value = str2double(app.TimeKnob.Value);
            app.Label.Text = string(value);
        end

        % Value changed function: CH1Knob
        function CH1KnobValueChanged(app, event)
            value = str2double(app.CH1Knob.Value);
            app.VLabel.Text = string(value) + "V";
        end

        % Value changed function: CH2Knob
        function CH2KnobValueChanged(app, event)
            value = str2double(app.CH2Knob.Value);
            app.VLabel_2.Text = string(value) + "V";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'CH1';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '1V';
            app.CH2Label = uilabel(app.UIFigure);
            app.CH2Label.Text = 'CH2';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = '1V';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.01';
            app.TimeKnob_2Label = uilabel(app.UIFigure);
            app.TimeKnob_2Label.Text = 'Time';
            app.TimeKnob = uiknob(app.UIFigure, 'discrete');
            app.TimeKnob.Items = {'0.01', '0.1', '1'};
            app.TimeKnob.ValueChangedFcn = createCallbackFcn(app, @TimeKnobValueChanged, true);
            app.TimeKnob.Value = '0.01';
            app.CH1Label_2 = uilabel(app.UIFigure);
            app.CH1Label_2.Text = 'CH1';
            app.CH1Knob = uiknob(app.UIFigure, 'discrete');
            app.CH1Knob.Items = {'0.01', '0.1', '0.5', '1', '2', '5'};
            app.CH1Knob.ValueChangedFcn = createCallbackFcn(app, @CH1KnobValueChanged, true);
            app.CH1Knob.Value = '1';
            app.CH2Label_2 = uilabel(app.UIFigure);
            app.CH2Label_2.Text = 'CH2';
            app.CH2Knob = uiknob(app.UIFigure, 'discrete');
            app.CH2Knob.Items = {'0.01', '0.1', '0.5', '1', '2', '5'};
            app.CH2Knob.ValueChangedFcn = createCallbackFcn(app, @CH2KnobValueChanged, true);
            app.CH2Knob.Value = '1';
            app.CH1CheckBox = uicheckbox(app.UIFigure);
            app.CH1CheckBox.Text = 'CH1';
            app.CH2CheckBox = uicheckbox(app.UIFigure);
            app.CH2CheckBox.Text = 'CH2';
            app.CHD1CheckBox = uicheckbox(app.UIFigure);
            app.CHD1CheckBox.Text = 'CHD1';
            app.CHD2CheckBox = uicheckbox(app.UIFigure);
            app.CHD2CheckBox.Text = 'CHD2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scope

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end